classdef ConnectionSetup < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        Panel              matlab.ui.container.Panel
        GridLayout         matlab.ui.container.GridLayout
        UIComponentsTable  matlab.ui.control.Table
        SaveButton         matlab.ui.control.Button
        CancelButton       matlab.ui.control.Button
        del                matlab.ui.control.Button
        add                matlab.ui.control.Button
    end

    
    properties (Access = private)
        selectedRow % Description
         % Description
    end
    
    properties (Access = public)
        done=0 % Description
        dataOut
    end
    
    methods (Access = public)
        
        function results = close(app)
            app.UIFigureCloseRequest;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, data)
            app.UIComponentsTable.Data=data;
            app.dataOut = data; %This is the data passed as outpout
        end

        % Button pushed function: add
        function addButtonPushed(app, event)
            t = app.UIComponentsTable.Data;
            index = size(t,1);
            ID = t.ID(index);
            struct_newItem.ID = ID+1;
            struct_newItem.Interface = 'GPIB';
            struct_newItem.Name = '8164B';
            struct_newItem.Type = 'Mainframe';      
            struct_newItem.Description = 'Lightwave Measurement System';
            struct_newItem.vendor = 'keysight';
            struct_newItem.ipaddress = "";
            struct_newItem.Board = 7;
            struct_newItem.GPIB = 0;
            struct_newItem.Timeout = 10;
            struct_newItem.Enabled = false; 
            
            t2 = struct2table(struct_newItem,'AsArray',1);
            t2.Properties.VariableNames('ipaddress')=("IP Address");
            
            t = [t;t2];
            t.Properties.RowNames{index+1} = num2str(struct_newItem.ID);
            app.UIComponentsTable.Data=t;
        end

        % Button pushed function: del
        function delButtonPushed(app, event)
            t = app.UIComponentsTable.Data;
            index = app.selectedRow;   
            
             
            for i=1:length(index)
                t(index(i),:) = []; 
            end
            
            app.UIComponentsTable.Data = t;
            app.selectedRow = [];
        end

        % Cell selection callback: UIComponentsTable
        function UIComponentsTableCellSelection(app, event)
            indices = event.Indices;
            indices = unique(indices(:,1));
            app.selectedRow = sort(indices,'descend');
            
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            configData = app.UIComponentsTable.Data;
            save('config.mat', 'configData','-mat');
            
            app.dataOut = configData;
            app.done = 1;
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.done = 1;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Panel = uipanel(app.UIFigure);
            app.GridLayout = uigridlayout(app.Panel);
            app.UIComponentsTable = uitable(app.GridLayout);
            app.UIComponentsTable.ColumnName = {'ID'; 'Interface'; 'Name'; 'Type'; 'Description'; 'Vendor'; 'IP Address'; 'Board'; 'GPIB Port'; 'Timeout'; 'Enabled'};
            app.UIComponentsTable.RowName = {''};
            app.UIComponentsTable.ColumnEditable = true;
            app.UIComponentsTable.CellSelectionCallback = createCallbackFcn(app, @UIComponentsTableCellSelection, true);
            app.SaveButton = uibutton(app.GridLayout, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.CancelButton = uibutton(app.GridLayout, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.del = uibutton(app.GridLayout, 'push');
            app.del.ButtonPushedFcn = createCallbackFcn(app, @delButtonPushed, true);
            app.del.Text = '-';
            app.add = uibutton(app.GridLayout, 'push');
            app.add.ButtonPushedFcn = createCallbackFcn(app, @addButtonPushed, true);
            app.add.Text = '+';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ConnectionSetup(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end