classdef ModeCalculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SquareWaveguideEffectiveIndexUIFigure  matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        GridLayout2                     matlab.ui.container.GridLayout
        WaveguidewidthnmEditFieldLabel  matlab.ui.control.Label
        WaveguidewidthnmEditField       matlab.ui.control.NumericEditField
        WaveguideheightnmEditFieldLabel  matlab.ui.control.Label
        WaveguideheightnmEditField      matlab.ui.control.NumericEditField
        GridLayout3                     matlab.ui.container.GridLayout
        RefractiveIndexCladdingEditFieldLabel  matlab.ui.control.Label
        RefractiveIndexCladdingEditField  matlab.ui.control.NumericEditField
        RefractiveIndexCoreEditFieldLabel  matlab.ui.control.Label
        RefractiveIndexCoreEditField    matlab.ui.control.NumericEditField
        GridLayout4                     matlab.ui.container.GridLayout
        RefractiveIndexSubstrateEditFieldLabel  matlab.ui.control.Label
        RefractiveIndexSubstrateEditField  matlab.ui.control.NumericEditField
        CalculateButton                 matlab.ui.control.Button
        PlotModeButton_2                matlab.ui.control.Button
        GridLayout5                     matlab.ui.container.GridLayout
        PolarizationDropDownLabel       matlab.ui.control.Label
        PolarizationDropDown            matlab.ui.control.DropDown
        WavelengthEditFieldLabel        matlab.ui.control.Label
        WavelengthEditField             matlab.ui.control.NumericEditField
        UITable                         matlab.ui.control.Table
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Fields % Description
        TableSelection% Description
        x % Description
        y % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            TableSelection = 0;
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            lambda_0 = app.WavelengthEditField.Value*1e-3;
            h = app.WaveguideheightnmEditField.Value*1e-3;   %Si layer thikness
            w = app.WaveguidewidthnmEditField.Value*1e-3;   %Waveguide width
            n_clad = app.RefractiveIndexCladdingEditField.Value; %Refractive index of cladding
            n_core = app.RefractiveIndexCoreEditField.Value; %Refractive index of the core
            n_subs = app.RefractiveIndexSubstrateEditField.Value; %Refractive index of the substrate
            p = app.PolarizationDropDown.Value;
            
            
            ne = [];  Polarization={}; Mode = []; Confinement = [];
            
            %% Calculation of the effective index of the vertical slab problem 
            d = h; n1=n_clad; n2 = n_core; n3 = n_subs;
            if(strcmp('TE',p)), pol = 'TE'; else, pol = 'TM'; end
            y=linspace(-2*h,3*h,1000); app.y = y;
            [ne1,Ev,Hv] = asym_slab(lambda_0,n1,n2,n3,d,pol,y);
            
            %% Calculation of the wave effective index (from the horizontal analysis) only for the fundamental vertical mode
            x=linspace(-2*w,3*w,1000);   app.x = x;
            for i = 1: length(ne1)
                d = w; n1 = n_clad; n2 = ne1(i); n3=n_clad;
                if(strcmp('TE',p)), pol = 'TM'; else, pol = 'TE'; end
                [ne2,Et,Hh] = asym_slab(lambda_0,n1,n2,n3,d,pol,x); %symmetric air bounded slab
                ne = [ne ne2];
            end
                   
            for i = 1: length(ne)
               Polarization{i} = p;
               Mode(i) = i;
               Index(i) = ne(i);
               
               
                Eh.x = -Et.y(i,:); Eh.y = Et.x(i,:); Eh.z = Et.z(i,:);
                
                if(strcmp('TE',p))
                   F = meshgrid(Ev.y,1:length(Ev.y))';
                   G = meshgrid(Eh.y,1:length(Eh.y));
                else
                   F = meshgrid(Ev.x,1:length(Ev.x))';
                   G = meshgrid(Eh.x,1:length(Eh.x));
                end
                
                E = F.*G;
                
                %Calculation of the confinement factor for the waveguide.
                Ein = E(x<w,y<h); x_in = x(x<w); y_in = y(y<h);
                Ein = Ein(x_in>0,y_in>0);%Stores the field inside the waveguide
                Iin = sum((Ein(:).^2)); Iall = sum((E(:).^2));
                Confinement(i) = Iin/Iall;  
                
                %Saves the mode field distribution
                app.Fields{i} = E;
               
            end
            
            t = table(Polarization',Mode',Index',Confinement');
            app.UITable.Data = t;
            
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;
            app.TableSelection=indices(1);
        end

        % Button pushed function: PlotModeButton_2
        function PlotModeButton_2Pushed(app, event)
            i = app.TableSelection;
            
            if (i == 0||i>length(app.Fields)), return; end

            ax = app.UIAxes;
            E = app.Fields{i};
            pcolor(ax,app.x,app.y,E.^2); shading(ax,'interp');
            
            ne = app.UITable.Data(i,:).Var3;
            gamma = app.UITable.Data(i,:).Var4;
            polarization = app.UITable.Data(i,:).Var1;
            
            if(strcmp('TE',polarization))
                title(ax, strcat('|Ex|^2 for ne=',num2str(ne),' and \Gamma = ',num2str(gamma)));
            else
                title(ax, strcat('|Ey|^2 for ne=',num2str(ne),' and \Gamma = ',num2str(gamma)));
            end
            colormap(ax,'hot');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SquareWaveguideEffectiveIndexUIFigure = uifigure('Visible', 'off');
            app.SquareWaveguideEffectiveIndexUIFigure.Name = 'Square Waveguide Effective Index';
            app.GridLayout = uigridlayout(app.SquareWaveguideEffectiveIndexUIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.WaveguidewidthnmEditFieldLabel = uilabel(app.GridLayout2);
            app.WaveguidewidthnmEditFieldLabel.Text = 'Waveguide width (nm)';
            app.WaveguidewidthnmEditField = uieditfield(app.GridLayout2, 'numeric');
            app.WaveguidewidthnmEditField.Value = 450;
            app.WaveguideheightnmEditFieldLabel = uilabel(app.GridLayout2);
            app.WaveguideheightnmEditFieldLabel.Text = 'Waveguide height (nm)';
            app.WaveguideheightnmEditField = uieditfield(app.GridLayout2, 'numeric');
            app.WaveguideheightnmEditField.Value = 220;
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.RefractiveIndexCladdingEditFieldLabel = uilabel(app.GridLayout3);
            app.RefractiveIndexCladdingEditFieldLabel.Text = 'Refractive Index Cladding';
            app.RefractiveIndexCladdingEditField = uieditfield(app.GridLayout3, 'numeric');
            app.RefractiveIndexCladdingEditField.Value = 1.446;
            app.RefractiveIndexCoreEditFieldLabel = uilabel(app.GridLayout3);
            app.RefractiveIndexCoreEditFieldLabel.Text = 'Refractive Index Core';
            app.RefractiveIndexCoreEditField = uieditfield(app.GridLayout3, 'numeric');
            app.RefractiveIndexCoreEditField.Value = 3.487;
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.RefractiveIndexSubstrateEditFieldLabel = uilabel(app.GridLayout4);
            app.RefractiveIndexSubstrateEditFieldLabel.Text = 'Refractive Index Substrate';
            app.RefractiveIndexSubstrateEditField = uieditfield(app.GridLayout4, 'numeric');
            app.RefractiveIndexSubstrateEditField.Value = 1.446;
            app.CalculateButton = uibutton(app.GridLayout4, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.PlotModeButton_2 = uibutton(app.GridLayout4, 'push');
            app.PlotModeButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlotModeButton_2Pushed, true);
            app.PlotModeButton_2.Text = 'Plot Mode';
            app.GridLayout5 = uigridlayout(app.GridLayout);
            app.PolarizationDropDownLabel = uilabel(app.GridLayout5);
            app.PolarizationDropDownLabel.Text = 'Polarization';
            app.PolarizationDropDown = uidropdown(app.GridLayout5);
            app.PolarizationDropDown.Items = {'TE', 'TM'};
            app.PolarizationDropDown.Value = 'TE';
            app.WavelengthEditFieldLabel = uilabel(app.GridLayout5);
            app.WavelengthEditFieldLabel.Text = 'Wavelength';
            app.WavelengthEditField = uieditfield(app.GridLayout5, 'numeric');
            app.WavelengthEditField.Value = 1550;
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'Polarization'; 'Mode'; 'Effective Index'; 'Confinement'};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            % Show the figure after all components are created
            app.SquareWaveguideEffectiveIndexUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ModeCalculator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SquareWaveguideEffectiveIndexUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SquareWaveguideEffectiveIndexUIFigure)
        end
    end
end