classdef SpectralM_v1_5_6 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        menu_ReconnectMenu              matlab.ui.container.Menu
        menu_ConnectionSetup            matlab.ui.container.Menu
        MeasurementMenu                 matlab.ui.container.Menu
        menu_SweepSave                  matlab.ui.container.Menu
        menu_SweepOpen                  matlab.ui.container.Menu
        ProjectMenu                     matlab.ui.container.Menu
        menu_projectSave                matlab.ui.container.Menu
        menu_projectSaveAs              matlab.ui.container.Menu
        menu_projectNew                 matlab.ui.container.Menu
        menu_projectOpen                matlab.ui.container.Menu
        ToolsMenu                       matlab.ui.container.Menu
        EffectiveIndexCalculationMenu   matlab.ui.container.Menu
        ResetControlsMenu               matlab.ui.container.Menu
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        Image                           matlab.ui.control.Image
        TabGroup2                       matlab.ui.container.TabGroup
        ProjectTab                      matlab.ui.container.Tab
        GridLayout15                    matlab.ui.container.GridLayout
        PlottingPanel                   matlab.ui.container.Panel
        GridLayout20                    matlab.ui.container.GridLayout
        optZaxis                        matlab.ui.control.DropDown
        zcoloraxisDropDownLabel         matlab.ui.control.Label
        optYaxis                        matlab.ui.control.DropDown
        yaxisDropDownLabel              matlab.ui.control.Label
        optXaxis                        matlab.ui.control.DropDown
        xaxisDropDownLabel              matlab.ui.control.Label
        btnPlot                         matlab.ui.control.Button
        ProjectManagementLabel          matlab.ui.control.Label
        MeasurementsPanel               matlab.ui.container.Panel
        GridLayout19                    matlab.ui.container.GridLayout
        btnDeleteMeasurement            matlab.ui.control.Button
        tblMeasurements                 matlab.ui.control.Table
        ControlTab                      matlab.ui.container.Tab
        GridLayout13                    matlab.ui.container.GridLayout
        SMUPanel                        matlab.ui.container.Panel
        GridLayout25                    matlab.ui.container.GridLayout
        sendButton                      matlab.ui.control.Button
        cmdEditField                    matlab.ui.control.EditField
        cmdEditFieldLabel               matlab.ui.control.Label
        dwSMU                           matlab.ui.control.DropDown
        InstrumentLabel_3               matlab.ui.control.Label
        AttenuatorPanel                 matlab.ui.container.Panel
        GridLayout23                    matlab.ui.container.GridLayout
        spAttChannel                    matlab.ui.control.Spinner
        dBmLabel_7                      matlab.ui.control.Label
        dBmLabel_6                      matlab.ui.control.Label
        EnableAttenuator                matlab.ui.control.StateButton
        OffsetSpinner                   matlab.ui.control.Spinner
        OffsetSpinnerLabel              matlab.ui.control.Label
        spAttenuation                   matlab.ui.control.Spinner
        AttenuationSpinnerLabel         matlab.ui.control.Label
        dwAttenuator                    matlab.ui.control.DropDown
        InstrumentLabel                 matlab.ui.control.Label
        OpticalSwitchPanel              matlab.ui.container.Panel
        GridLayout11                    matlab.ui.container.GridLayout
        swAKnob                         matlab.ui.control.DiscreteKnob
        PowerSensorPanel                matlab.ui.container.Panel
        GridLayout10                    matlab.ui.container.GridLayout
        spPMSlot                        matlab.ui.control.Spinner
        spPMChannel                     matlab.ui.control.Spinner
        Label_3                         matlab.ui.control.Label
        dwSensor                        matlab.ui.control.DropDown
        InstrumentLabel_2               matlab.ui.control.Label
        panel01_dBmLabel_2              matlab.ui.control.Label
        cbPMAutoRange                   matlab.ui.control.CheckBox
        btnPMRead                       matlab.ui.control.StateButton
        panel01_msLabel                 matlab.ui.control.Label
        panel01_pwrMeas1                matlab.ui.control.Label
        dwPMRange                       matlab.ui.control.DropDown
        panel01_RangeDropDownLabel      matlab.ui.control.Label
        panel01_AveragingTSpinner       matlab.ui.control.Spinner
        AvgTLabel                       matlab.ui.control.Label
        LaserControlPanel               matlab.ui.container.Panel
        GridLayout9                     matlab.ui.container.GridLayout
        btnLaserOn                      matlab.ui.control.StateButton
        dBmLabel                        matlab.ui.control.Label
        nmLabel                         matlab.ui.control.Label
        txtPower                        matlab.ui.control.Spinner
        panel01_OutputPowerSpinnerLabel  matlab.ui.control.Label
        txtWavelength                   matlab.ui.control.Spinner
        panel01_WavelengthSpinnerLabel  matlab.ui.control.Label
        ConnectionPanel                 matlab.ui.container.Panel
        GridLayout8                     matlab.ui.container.GridLayout
        panel01_LaserOutputLamp         matlab.ui.control.Lamp
        panel01_LaserOutputLampLabel    matlab.ui.control.Label
        panel01_instrConnStat           matlab.ui.control.Lamp
        panel01_InstrSelect             matlab.ui.control.DropDown
        panel01_InstrumentDropDownLabel  matlab.ui.control.Label
        LightwaveMeasurementSystemLabel  matlab.ui.control.Label
        MeasurementTab                  matlab.ui.container.Tab
        GridLayout17                    matlab.ui.container.GridLayout
        SMUSweepPanel                   matlab.ui.container.Panel
        GridLayout24                    matlab.ui.container.GridLayout
        SMU_units_sto                   matlab.ui.control.Label
        SMU_Enabled                     matlab.ui.control.CheckBox
        SMU_units_sta                   matlab.ui.control.DropDown
        SMU_nos                         matlab.ui.control.NumericEditField
        NumberofstepsEditFieldLabel     matlab.ui.control.Label
        SMU_stop                        matlab.ui.control.NumericEditField
        StopEditFieldLabel              matlab.ui.control.Label
        SMU_start                       matlab.ui.control.NumericEditField
        StartEditFieldLabel             matlab.ui.control.Label
        SMU_type                        matlab.ui.control.DropDown
        ParameterSweepDropDownLabel     matlab.ui.control.Label
        AttenuationSweepPanel           matlab.ui.container.Panel
        GridLayout22                    matlab.ui.container.GridLayout
        dBLabel_2                       matlab.ui.control.Label
        dBLabel                         matlab.ui.control.Label
        AttenuationSteps                matlab.ui.control.Spinner
        StepsSpinnerLabel               matlab.ui.control.Label
        AttenuationStoper               matlab.ui.control.Spinner
        StopSpinnerLabel                matlab.ui.control.Label
        AttenuationStart                matlab.ui.control.Spinner
        StartSpinnerLabel               matlab.ui.control.Label
        AttenuationEnabled              matlab.ui.control.CheckBox
        LossMeasurementWavelengthScanPanel  matlab.ui.container.Panel
        GridLayout18                    matlab.ui.container.GridLayout
        spinSpeed                       matlab.ui.control.DropDown
        ScanSpeedLabel                  matlab.ui.control.Label
        pmLabel                         matlab.ui.control.Label
        nmLabel_4                       matlab.ui.control.Label
        nmLabel_3                       matlab.ui.control.Label
        spinStep                        matlab.ui.control.Spinner
        StepSizeSpinnerLabel            matlab.ui.control.Label
        spinStop                        matlab.ui.control.Spinner
        ScanStopSpinnerLabel            matlab.ui.control.Label
        spinStart                       matlab.ui.control.Spinner
        ScanStartSpinnerLabel           matlab.ui.control.Label
        MeasurementParametersLabel      matlab.ui.control.Label
        DataAnalysisTab                 matlab.ui.container.Tab
        GridLayout16                    matlab.ui.container.GridLayout
        RemoveBackgroundPanel           matlab.ui.container.Panel
        GridLayout3_2                   matlab.ui.container.GridLayout
        ui_btnRemoveBack                matlab.ui.control.Button
        txtHPB                          matlab.ui.control.NumericEditField
        HighPassBandFreqLabel           matlab.ui.control.Label
        DataAnalysisParametersLabel     matlab.ui.control.Label
        InterferenceAnalysisPanel       matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        txtneff                         matlab.ui.control.NumericEditField
        neffectiveEditFieldLabel        matlab.ui.control.Label
        optTOD                          matlab.ui.control.DropDown
        TypeofdeviceDropDownLabel       matlab.ui.control.Label
        txtResL                         matlab.ui.control.NumericEditField
        LnmLabel                        matlab.ui.control.Label
        Peakfinder1RedPanel             matlab.ui.container.Panel
        GridLayout7                     matlab.ui.container.GridLayout
        MinPeakDistanceCheckBox         matlab.ui.control.CheckBox
        MinPeakWidthCheckBox            matlab.ui.control.CheckBox
        MaxPeakWidthCheckBox            matlab.ui.control.CheckBox
        MinPeakProminenceCheckBox       matlab.ui.control.CheckBox
        MinPeakHeightCheckBox           matlab.ui.control.CheckBox
        nmLabel_8                       matlab.ui.control.Label
        nmLabel_7                       matlab.ui.control.Label
        nmLabel_6                       matlab.ui.control.Label
        dBmLabel_5                      matlab.ui.control.Label
        DistanceSpinner                 matlab.ui.control.Spinner
        PeakSpacingLabel                matlab.ui.control.Label
        MinWidthSpinner                 matlab.ui.control.Spinner
        MinWidthSpinnerLabel            matlab.ui.control.Label
        MaxWidthSpinner                 matlab.ui.control.Spinner
        MaxWidthSpinnerLabel            matlab.ui.control.Label
        ProminenceSpinner               matlab.ui.control.Spinner
        ProminenceSpinnerLabel          matlab.ui.control.Label
        HeightSpinner                   matlab.ui.control.Spinner
        HeightSpinnerLabel              matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        GridLayout21                    matlab.ui.container.GridLayout
        logger                          matlab.ui.control.TextArea
        ui_displayPeaksCheckBox         matlab.ui.control.CheckBox
        ui_btnRunSweep                  matlab.ui.control.StateButton
        ui_btnFindPeaks                 matlab.ui.control.Button
        tblResonanceAnalysis            matlab.ui.control.Table
        UIAxes01                        matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        Timer           % Timer for real time measurements
        COM_config      % Communication configuration data
        
        connPanel01     % Description
        LockLaser       % Description
        saveFolder      % Description
        mData           % Description
        
        measurement     % Structure with members {Pseudrandom Identification (), Sweep Parameters, Spectra, Analysis Parameters, Peak Analysis results}
        project         % Structure with measurements of type sweep {Pseudrandom Identification (), Sweep Parameters, Spectra, Analysis Parameters, Peak Analysis results}
        selectedRow     % Row selected on the measurements tableUI
        pm_channel      % Description
        pm_conn         % Description
        att_channel     % Description
        att_conn        % Description
        pm_slot         % Slot of the currently selected power meter
    end
    
    properties (Access = public)
        connection % Description
    end
    
    methods (Access = private)
        
        function results = setWorkspace(app, configData)
             %Delete any existing instruments.
            i = instrfind(); 
            if ~isempty(i) 
                fclose(i); delete(i), clear i;
            end
            
            %Sets the updated data in the data table
            configData.Properties.RowNames = string(configData.ID);
            app.COM_config = configData;
            
            %Define the instrument connections 
            app.connection =  newconnection(configData);
                      
            %% Fill in all combo boxes and update control enabled state
            ui_enableControls(app,0);
            updateControls(app);
            panel01_InstrSelectValueChanged(app, 1); %Enables the default LMS connection.
        end
        
        function results = TimerFcn(app,~,~)
            g = app.pm_conn; 
            query_pm(app,g);
        end
        
        function query_pm(app, g)
            %Mouse flicker to prevent screen saver
            pos  =get(0,'PointerLocation');
            set(0,'PointerLocation',[pos(1)+10 pos(2)+10]);
            pause(1e-5);
            set(0,'PointerLocation',[pos(1) pos(2)]);
            
            slot = app.pm_slot;
            channel = app.pm_channel;
            
            if ~isempty(g)
                if isa(g,'visa')
                    if strcmp(g.status,'closed') 
                        stop(app.Timer);
                        return;
                    end
                end
                
                try
                    sm_flush(g);
                    val = str2double(send(g,'fetc%1d:chan%1d:pow?',slot,channel));
                    if (val>3e38)
                        app.panel01_pwrMeas1.Text = 'err';
                    else
                        app.panel01_pwrMeas1.Text = num2str(val,'%.4f');
                    end
                    
                catch Ex
                    ui_log(app, Ex.message);
                    setPowerScan(app, 0);
                    stop(app.Timer);
                end
            else
                stop(app.Timer);
            end
        end
        
        
        function ui_log(app, text, varargin)
            if isempty(varargin)
                 text = char(text);
            else
                 text = char(sprintf(text,varargin{1:end}));
            end

            oldlogText = app.logger.Value;
            maxLines = 100;
            if length(oldlogText)<maxLines
                text = [text; oldlogText];
            else
                text = [text;oldlogText(1:maxLines-1)];
            end
            app.logger.Value = text;
        end
        
        function results = project2h5(app,file)
            app.project.type = 'Spectral Measurements Project';
            app.project.datasize = length(app.project.measurements);
            struct2h5(file, '/project', app.project)
        end
        
        function results = ui_updatePowChannSpinner(app)
            ID = (app.dwSensor.Value);
            if ~isempty(ID)
                idx = find(abs([app.connection.ID]-ID)<=1e-5);
                instrx = app.connection(idx).instr; %   
                slot = app.connection(idx).slot;
                slotconfig = app.connection(idx).slotconfig;
                
                if instrx.nslots >1
                    app.spPMSlot.Enable = 1;
                    app.spPMSlot.Limits = [1, instrx.nslots];
                    app.spPMSlot.Value = 1;
                else
                    app.spPMSlot.Enable = 0;
                    app.spPMSlot.Limits = [slot, slot+1];
                    app.spPMSlot.Value = slot;
                end
                
                if (isempty(slotconfig))
                    app.spPMChannel.Enable = 0;
                    app.spPMChannel.Limits = [0 1];
                    app.spPMChannel.Value = 1;
                else
                    nchan = length(slotconfig(1).channel);
                    if(nchan > 1)
                        app.spPMChannel.Enable = 1;
                        app.spPMChannel.Limits = [slotconfig(1).channel(1), slotconfig(1).channel(nchan)];
                        app.spPMChannel.Value = slotconfig(1).channel(1);
                    else
                        app.spPMChannel.Enable = 0;
                        app.spPMChannel.Limits = [0, slotconfig(1).channel(1)];
                        app.spPMChannel.Value = slotconfig(1).channel(1);
                    end
                end
                
                
            end
        end


        function results = ui_selectLMS(app)
            ID = (app.panel01_InstrSelect.Value);
            results = [];
            if ~isempty(ID)
                idx = find(abs([app.connection.ID]-ID)<=1e-5);
                results = app.connection(idx);
            else
                errID = 'SpectralM:ConnectionNotFound';
                msg = 'Could not find a connection for a Lightwave Measurement System.';
                ME = MException(errID,msg);
                %throw(ME);
                ui_log(app,strcat(errID,":",msg));
                
                instr.config = []; instr.ID = []; instr.type = []; instr.status = 0;
                results = instr;
            end
        end
        
        function results  = ui_selectPM(app)
            ID = (app.dwSensor.Value);
            results = [];
            if ~isempty(ID)
                idx = find(abs([app.connection.ID]-ID)<=1e-5);
                results = app.connection(idx);
            else
                errID = 'SpectralM:ConnectionNotFound';
                msg = 'Could not find a connection for a Power Meter Unit.';
                ME = MException(errID,msg);
                throw(ME);
            
            end
        end
        
        function connection  = ui_selectAtt(app)
            ID = (app.dwAttenuator.Value);
            if ~isempty(ID)
                idx = find(abs([app.connection.ID]-ID)<=1e-5);
                connection = app.connection(idx);
            else
                errID = 'SpectralM:ConnectionNotFound';
                msg = 'Could not find a connection for an attenuator.';
                ME = MException(errID,msg);
                throw(ME);
            end
        end

        function connection  = ui_selectSMU(app)
            ID = (app.dwSMU.Value);
            if ~isempty(ID)
                idx = find(abs([app.connection.ID]-ID)<=1e-5);
                connection = app.connection(idx);
            else
                errID = 'SpectralM:ConnectionNotFound';
                msg = 'Could not find a connection for an attenuator.';
                ME = MException(errID,msg);
                throw(ME);
            end
        end
        
        function results = ui_enableControls(app,control,value)
            if nargin <3
                value = control;
                control = "all";
            end
            
            switch control
                case "PM"
                    app.btnPMRead.Enable = value;
                    app.dwSensor.Enable = value;
                    
                case "OSW"
                    app.swAKnob.Enable = value;
                    
                case "LMS"
                    app.panel01_instrConnStat.Enable = value;                      
                    app.ui_btnRunSweep.Enable = value;
                    app.btnLaserOn.Enable = value;
                    app.panel01_InstrSelect.Enable = value;
                    
                case "ATT"
                    app.EnableAttenuator.Enable = value; % For direc control
                    app.AttenuationEnabled.Enable = value; % For measurements
                    app.OffsetSpinner.Enable=value;
                    app.spAttenuation.Enable=value;
                    app.spAttChannel.Enable = value;
                case "SMU"    
                    app.dwAttenuator.Enable = value;
                    app.SMU_Enabled.Enable = value;
                otherwise     
                    app.panel01_instrConnStat.Enable = value;                      
                    app.swAKnob.Enable = value;
                    app.ui_btnRunSweep.Enable = value;
                    app.btnPMRead.Enable = value;
                    app.btnLaserOn.Enable = value;
                    app.panel01_InstrSelect.Enable = value;
                    
                    app.EnableAttenuator.Enable = value;
                    app.OffsetSpinner.Enable = value;
                    app.spAttenuation.Enable=value;
                    app.spAttChannel.Enable = value;
            end
        end
        
        
        function results = ui_updateData(app)
            cla(app.UIAxes01);
            for i = 1:length(app.selectedRow)
                if length(app.selectedRow) > 1 
                   row = app.selectedRow(i);
                else
                    row = app.selectedRow;
                end
                ID = app.tblMeasurements.Data(row,:).ID;
                index = getIndex(app.project.measurements,ID);
                app.measurement = app.project.measurements{index};
                wav_scale = 1e9;
                 
                %Plot and display results of the active dataset and analytsis 
                pks = {}; try pks = app.measurement.peakAnalysis.pks; catch, end
                wav = {}; try wav = app.measurement.peakAnalysis.wav*wav_scale; catch, end
                
                if  ~isempty(pks), if pks == 0, pks = {};end,end
                
                spectra = app.measurement.spectra;
               
                if ~isempty(pks) && app.ui_displayPeaksCheckBox.Value
                    ran = max(spectra(:,1))-min(spectra(:,1));
                    s = plot (app.UIAxes01,spectra(:,1)*wav_scale,real(spectra(:,2)), wav, pks,'V','MarkerFaceColor','red'); 
                    
                    s(1).DataTipTemplate.DataTipRows(1).Label = '\lambda =';
                    s(1).DataTipTemplate.DataTipRows(2).Label = 'Power =';
                    
                    s(1).DataTipTemplate.DataTipRows(1).Format = "%04.4f%*9";
                    s(1).DataTipTemplate.DataTipRows(2).Format = "%04.2f%*9";
                    
                    xlabels = cellstr(num2str(wav));
                    lbls=cell(length(pks),1);
                    for j =1:length(pks)
                        lbls{j}=xlabels{j};%Label for the plot peak 
                    end
                    text(app.UIAxes01,wav+ran/40, pks,lbls);
                    
                    if i == length(app.selectedRow)
                        %Update the table only in the last iteration
                        t = getTableAnalysis(app.measurement.peakAnalysis);
                        app.tblResonanceAnalysis.Data = t;
                    end
                else
                    s = plot (app.UIAxes01,spectra(:,1)*wav_scale,real(spectra(:,2)));
                    
                    s.DataTipTemplate.DataTipRows(1).Label = '\lambda =';
                    s.DataTipTemplate.DataTipRows(2).Label = 'Power =';
                    
                    s.DataTipTemplate.DataTipRows(1).Format = "%04.4f%*9";
                    s.DataTipTemplate.DataTipRows(2).Format = "%04.2f%*9";
                    
                    if ~isempty(pks)
                        t = getTableAnalysis(app.measurement.peakAnalysis);
                        app.tblResonanceAnalysis.Data = t;
                    end
                end
                hold(app.UIAxes01,'on');
        
                
            end
            hold(app.UIAxes01,'off');      
            enableDefaultInteractivity(app.UIAxes01);
        end
        
        function results = setPowerScan(app, value)
            c_pm = ui_selectPM(app);
            stop(app.Timer);
            if c_pm.status
                g = c_pm.instr;
                if ~value 
                    closeGPIB(g);
                else
                    openGPIB(g);
                    send(g,'*CLS');
                    opcStat = str2double(send(g,'*OPC?')); 
                    
                    if opcStat
                        range = str2double(app.dwPMRange.Value);    %Range in dBm
                        avgT = app.panel01_AveragingTSpinner.Value*1e-3; %Averaging time in seconds
                        slot = app.spPMSlot.Value;
                        channel = app.spPMChannel.Value;
                        
                        send(g,'init%1d:cont 1',slot);
                        send(g,'sens%1d:pow:unit dbm',slot);
                        send(g,"sens%1d:pow:atim %5.2fs",slot,avgT);
                        avgT = str2double(send(g,"sens%1d:pow:atim?",slot));
                        if ~app.cbPMAutoRange.Value
                            send(g,"sens%1d:pow:rang:auto 0",slot);
                            send(g,"sens%1d:pow:rang %1d",slot,range);
                            range =  str2double(send(g,"sens%1d:pow:rang?",slot));
                            ui_log(app,"Configured sensor with averaging time: " + avgT + "s and range " + num2str(range,'%5.2f') +' dBm.');
                        else
                            send(g,"sens%1d:pow:rang:auto 1",slot);
                            ui_log(app,"Configured sensor with averaging time: "+avgT+"s and automatic range.");
                        end
                        app.pm_conn = g.connection; 
                        app.pm_slot = slot; 
                        app.pm_channel = channel;
                        start(app.Timer);
                    else
                        %app.Switch.Value = false;
                        closeGPIB(g);
                        log(app,"The instrument is not ready.")
                    end   
                end
            end
        end
        
        function connect_LMS(app)
            c_ls = ui_selectLMS(app);
            ui_enableControls(app,'LMS',0);    
            if isempty(c_ls)
                log(app,"No Lightwave Measurement Systems detected.")
                app.ui_btnRunSweep.Value = 0;
                return; 
            elseif c_ls.status
                ui_enableControls(app,'LMS',1);
            end
        end
        
        function results = setLaserWav(app, g, wav)
            %Set the laser central wavelenght and returns the configured value in nm
            opcStat = wait_OPC(g, 2);
            if isnan(opcStat)
                return;
            end
            if opcStat
                send(g,"sour0:chan1:wav " + num2str(wav,'%5.3f')+'nm');    
                wait_OPC(g, 2);
                results = str2double(send(g,'sour0:chan1:wav?')).*1e9; 
                
            else
                errID = 'SpectralM:InstrumentBusy';
                msg = 'The instrument you are trying to reach cannot accept commands.';
                ME = MException(errID,msg);
                throw(ME);
            end
        end
        
        function results = setLaserPower(app, g,pow)
            opcStat = str2double(send(g,'*OPC?')); 
            if opcStat==1
                %Set the power unit as dBm
                send(g,'sour0:pow:unit dbm');
                send(g,"sour0:chan1:pow " + num2str(pow,'%5.2f'));
                results = str2double(send(g,'sour0:chan1:pow?'));
            else
                errID = 'SpectralM:InstrumentBusy';
                msg = 'The instrument you are trying to reach cannot accept commands.';
                ME = MException(errID,msg);
                %throw(ME); %% Udpate this message
                warning(msg);
            end
        end
        
        function [pks,wav,filter]  = SM_FindPeaks(app, spectra)
            prominence = app.ProminenceSpinner.Value;
             correct = 0;
            if (prominence<0)
                prominence = -prominence;
                spectra(:,2) = -spectra(:,2);
                correct = 1;
            end
            

            filterStat{1} = [ app.MinPeakHeightCheckBox.Value,...
                        app.ProminenceSpinner.Value,...
                        app.MaxPeakWidthCheckBox.Value,...
                        app.MinPeakWidthCheckBox.Value,...
                        app.MinPeakDistanceCheckBox.Value];
            
            filterParam{1} = [app.HeightSpinner.Value,...
                        prominence,...
                        app.MaxWidthSpinner.Value,...
                        app.MinWidthSpinner.Value,...
                        app.DistanceSpinner.Value
                        ];
            filter.stat=filterStat{1};
            filter.param = filterParam{1};


            [pks,wav]=getPeaks(spectra,filterStat{1},filterParam{1});
             if (correct == 1)
                pks = -pks;
            end
        end
        
        function updateControls(app)
            try
                [comboStrings,comboValues]= getComboData(app.connection, 'Mainframe');
                app.panel01_InstrSelect.Items = comboStrings;
                app.panel01_InstrSelect.ItemsData = comboValues;
                if ~isempty(comboValues), ui_enableControls(app,'LMS',1); end
               
                
                [~,comboValues]= getComboData(app.connection, 'Switch');
                if ~isempty(comboValues), ui_enableControls(app,'OSW',1); end
                
                [comboStrings,comboValues]= getComboData(app.connection, 'Power Meter');
                app.dwSensor.Items = comboStrings;
                app.dwSensor.ItemsData = comboValues;
                ui_updatePowChannSpinner(app);
               
                if ~isempty(comboValues), ui_enableControls(app,'PM',1); end
                
                [comboStrings,comboValues]= getComboData(app.connection, 'Attenuator');
                app.dwAttenuator.Items = comboStrings;
                app.dwAttenuator.ItemsData = comboValues;
                if ~isempty(comboValues), ui_enableControls(app,'ATT',1); end
                
                [comboStrings,comboValues]= getComboData(app.connection, 'SMU');
                app.dwSMU.Items = comboStrings;
                app.dwSMU.ItemsData = comboValues;
                if ~isempty(comboValues), ui_enableControls(app,'SMU',1); end
             catch ME
                ui_log(app, ME.message);  ui_log(app, ME.stack(1))
            end
        end
        
        function ui_runSweep(app, value)
            clc;
            
            c_ls = ui_selectLMS(app);
            c_pm = ui_selectPM(app);
            
            if isempty(c_ls) || isempty(c_pm)
                ui_log(app,"No instruments available to carry a sweep.")
                app.ui_btnRunSweep.Value = 0;
                return; 
            end
            
            externalPM = ~(c_pm.config.ID == c_ls.config.ID); %%Are we using a power meter separate from the laser?
            ui_log(app,"External PM detected.");
            if value
                ui_log(app,"Running wavelength sweep.");
                pm_running = app.btnPMRead.Value;
                ls_running = app.btnLaserOn.Value;
                if c_ls.status && c_pm.status 
                    g_ls = c_ls.instr.connection;
                    g_pm = c_pm.instr.connection;
                    sm_flush(g_ls), sm_flush(g_pm);
                    
                    stop(app.Timer);
                    app.btnPMRead.Value = 0;
                    app.btnLaserOn.Value = 0;
    
                    openGPIB(g_ls); %This will leave a GPIB connection open during the whole measurement, and do nothing otherwise
                    openGPIB(g_pm);
                    
                    send(g_ls,'*CLS');
                    opcStat = str2double(send(g_ls,'*OPC?'));
                    
                    if opcStat
                        ui_log(app,"Instrument ready for sweep.");
                        ui_enableControls(app, 0);
                        %try 
                            sweep_att = app.AttenuationEnabled.Value;
                            sweep_smu = app.SMU_Enabled.Value;

                            if sweep_att && sweep_smu
                                ui_log(app,"Error: Only one parametric sweep can be run simultaniously.");
                                return
                            end
                            var_attribute=[0];
                            smu_chan = 0;
                            if sweep_att
                                var2_text = 'Attenuation';
                                var2_start = app.AttenuationStart.Value;
                                var2_stop = app.AttenuationStoper.Value;
                                var2_steps = app.AttenuationSteps.Value;
                                var_attribute = linspace(var2_start,var2_stop,var2_steps);
                                att_chan = app.spAttChannel.Value;
                                if att_chan == 2, att_chan =3; end
                                comp = ui_selectAtt(app);
                                if comp.status
                                   g_comp = comp.instr;
                                end
                            elseif sweep_smu
                                var2_text = 'SMU'
                                if strcmp(app.SMU_type.Value, 'Voltage')
                                    smu_mode = 'VOLT';
                                else
                                    smu_mode = 'CURR';
                                end
                                vopt = {'V','mV','uV'}; iopt = {'A','mA','uA'};
                                mult = 1; 
                                val = app.SMU_units_sta.Value;
                                for idx = 1:size(vopt',1)
                                    if strcmp(vopt{idx},val)||strcmp(iopt{idx},val), break; end
                                    mult = mult*1e-3;  
                                end

                                var2_start = app.SMU_start.Value*mult;
                                var2_stop = app.SMU_stop.Value*mult;
                                var2_steps = app.SMU_nos.Value;
                                var_attribute = linspace(var2_start,var2_stop,var2_steps);
                                smu_chan = 1;
                                comp = ui_selectSMU(app);
                                if comp.status
                                   g_comp = comp.instr;
                                end

                                send(g_comp,'SOUR%1d:FUNC:MODE %s',smu_chan,smu_mode)
                                send(g_comp,'OUTP%1d ON',smu_chan)
                            end


                            for idx2 = 1:length(var_attribute)    
                                new_measurement = newMeasurement();
                                
                                %Read the user parameters from the UI
                                sweepParam = {};
                                sweepParam.ls_slot=   0; 
                                
                                sweepParam.pm_channel =  app.spPMChannel.Value;
                                
                                sweepParam.att_channel = app.spAttChannel.Value;
                                sweepParam.externalPM =  externalPM;                    % Is this the same as sweepParam.self
                                
                                sweepParam.power    = app.txtPower.Value;               % Power in dBm (3dBm = 2mW) 
                                sweepParam.starWav  =  app.spinStart.Value*1e-9;        % start wavelength in m
                                sweepParam.stopWav  =  app.spinStop.Value*1e-9;         % stop wavelength in m
                                sweepParam.step     =     app.spinStep.Value*1e-12;     % stepsize in meters
                                sweepParam.sweepSpeed =  str2num(app.spinSpeed.Value)*1e-9;  % sweep speed in m/s
                                sweepParam.range    = str2double(app.dwPMRange.Value);  % range in dBm in steps of 10, between [-70 and 40];
                                sweepParam.avgTime  = 0.001;    %s
                                sweepParam.trigs    = 1;       % This is automatically set by the setup_ls function
                                
                                
                                if (send(g_pm,'*IDN?')==send(g_ls,'*IDN?'))
                                    sweepParam.self = true;
                                    %sweepParam.pm_slot =  c_pm.slot;
                                    sweepParam.pm_slot = app.spPMSlot.Value;
                                else
                                    sweepParam.self = false;
                                    sweepParam.pm_slot =  app.spPMSlot.Value;
                                end
                                
                                                             
                                %Turn off laser and park at the start wavelength
                                send(g_ls,"outp 0"); 
                                laserStat = str2double(send(g_ls,'outp?'));
                                setLaserWav(app, g_ls, sweepParam.starWav*1e9);
                                wait_OPC(g_ls, 1);

                                if sweep_att
                                   if comp.status
                                       ui_log(app,"Setting Att to %3.1f", var_attribute(idx2) );
                                       send(g_comp,'INP%1d:ATT %1ddB',att_chan,var_attribute(idx2));
                                   end
                                elseif sweep_smu
                                   if comp.status
                                       ui_log(app,"Setting SMU to %3.5f", var_attribute(idx2) );
                                       send(g_comp,'SOUR%1d:%s %3.6f',smu_chan,smu_mode,var_attribute(idx2));
                                   end
                                end
                                
                                % Prepare scan
                                ui_log(app,"Preparing laser.");
                                sweepParam = setup_ls(g_ls, sweepParam); % Always configure first the laser, to update the parameters based on the laser setup.
                                ui_log(app,"Preparing power meter");
                                sweepParam = setup_pm(g_pm, sweepParam);
                                         
                                new_measurement.spectra = [];
                                ui_log(app,"Running sweep");
                                new_measurement.spectra = runSweep(g_ls, g_pm,sweepParam);
                                if (isempty(new_measurement.spectra))
                                    ui_log(app,"Didn't receive any data from the sweep.");
                                    
                                    continue;
                                end
                                
                                if sweepParam.self
                                    send(g_pm, 'trig1:inp IGN'); 
                                    ui_log(app, 'Reset PM Trig1: %s', send(g_pm, 'trig1:inp?'));
                                else
                                    send(g_pm, 'trig2:inp IGN');
                                    ui_log(app, 'Reset PM Trig2: %s', send(g_pm, 'trig2:inp?'));
                                end
                                

                                new_measurement.sweepParam = sweepParam;
                                new_measurement.var(1) = var_attribute(idx2);
                                new_measurement.desc = "";
                                
                                %Update the global variables
                                app.measurement = new_measurement;
                                dataSize = length(app.project.measurements);
                                app.project.measurements{dataSize+1} = app.measurement;
                                app.selectedRow = dataSize+1;
                                
                                t = getTableData(app.project.measurements);
                                app.tblMeasurements.Data = t;
                            end
                            updateControls(app);
                            app.spPMSlot.Value = sweepParam.pm_slot;
                            app.spPMChannel.Value = sweepParam.pm_channel;
                            app.spAttChannel.Value = sweepParam.att_channel;
                            
                            ui_updateData(app);
                    end


                    app.btnPMRead.Value = pm_running;
                    ui_btnPMReadChanged(app,1);
                    
                    app.btnLaserOn.Value = ls_running;
                    ui_btnLaserOn(app, 1)
                end
                closeGPIB(g_ls);
                closeGPIB(g_pm);
            end
            
            app.ui_btnRunSweep.Value = 0;
        end
           
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath(pwd+"\dependencies")
            
            % Clear any exisiting GPIB instrument.
            i = instrfindall;
            if  ~isempty(i), fclose(i);instrreset; end

            %Load configuration options
            t = load('config.mat','configData');
            configData = t.configData;
           
            %Configure the timer for the power meter reading.
            app.Timer = timer('Period',0.1,...
                'ExecutionMode', 'fixedSpacing', ...
                'TasksToExecute', Inf);
            app.Timer.TimerFcn = @app.TimerFcn;
            
            setWorkspace(app, configData);
            
            %Initilize an empty project variable
                app.project.measurements = {};
                app.project.filename = '';
                ID = zeros(0,1); Var1 = ID; Var2 = ID; Var3 = ID; Description = strings(0,1); Date = strings(0,1); %Initialize data for the table
                t = table(ID,Date,Description,Var1,Var2,Var3);
                app.tblMeasurements.Data = t;
             app.saveFolder = "%userprofile%\documents";  %Set the default save directory
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            stop(app.Timer);
            delete(instrfind);
            delete(app);
        end

        % Value changed function: dwSensor
        function ui_dwSensorChanged(app, event)
            %value = app.dwSensor.Value;
            ui_updatePowChannSpinner(app);
        end

        % Cell edit callback: tblMeasurements
        function ui_tblMeasurementsCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
            ID = app.tblMeasurements.Data(indices(1),:).ID;
            i = getIndex(app.project.measurements,ID);
            if (indices(2) == 3) %Change in the Desc column
                app.project.measurements{i}.desc = newData;
            else
                app.project.measurements{i}.var(indices(2)-3)=newData;
            end        
        end

        % Cell selection callback: tblMeasurements
        function ui_tblMeasurementsCellSelection(app, event)
            indices = event.Indices;
            app.selectedRow = indices(:,1);        
            ui_updateData(app);
        end

        % Value changed function: ui_btnRunSweep
        function ui_btnRunSweepValueChanged(app, event)
            ui_runSweep(app,event.Value);
        end

        % Value changed function: btnLaserOn
        function ui_btnLaserOn(app, event)
            value = double(app.btnLaserOn.Value);
            %Sends a command to turn the laser on and updates the laser
            c_ls = ui_selectLMS(app);
            if c_ls.status
                g = c_ls.instr; sm_flush(g);
                send(g,"outp %1d",value);
            
                opcStat = wait_OPC(g, 2);
                laserStat = str2double(send(g,'outp?'));
                app.panel01_LaserOutputLamp.Enable = laserStat;
                if laserStat==1
                    setLaserWav(app,g, app.txtWavelength.Value);
                    setLaserPower(app,g, app.txtPower.Value);
                end
                    
            end
            
        end

        % Value changed function: EnableAttenuator
        function ui_btnEnableAttenuator(app, event)
            value = app.EnableAttenuator.Value;
            try  
               c_att = ui_selectAtt(app);
               if c_att.status
                   g = c_att.instr;
                   
                   app.spAttChannel.Enable = ~value;
                   app.dwAttenuator.Enable = ~value;
                   channel = app.spAttChannel.Value;
                   if channel == 2, channel =3; end
                   
                   if value
                       att = app.spAttenuation.Value;  
                       off = app.OffsetSpinner.Value; 
                       wav = app.txtWavelength.Value;
                       
                       send(g,'INP%1d:ATT %1ddB',channel,att);
                       send(g,'INP%1d:OFFS %1ddB',channel,off);
                       send(g,'INP%1d:WAV %1.4fnm',channel,wav);
                       send(g,'OUTP%1d:POW:CONTR 0',channel);    %For now, the app doesn't support power control, and instead gives only control over the attenuation factor
                       send(g,'OUTP%1d:STAT %1d',channel,1);            % Open the shutter
                       
                       app.spAttChannel.Enable = false;
                       app.dwAttenuator.Enable = false;
                       
                   else
                       app.spAttChannel.Enable = true;
                       
                       send(g,'INP%1d:ATT %1ddB',channel,0);
                       send(g,'INP%1d:OFFS %1ddB',channel,0);
                       send(g,'OUTP%1d:POW:CONTR 0',channel);    
                       
                       send(g,'OUTP%1d:STAT %1d',channel,0);            % Close the shutter
                       
                       app.spAttChannel.Enable = true;
                       app.dwAttenuator.Enable = true;
                   end
                   app.att_conn = g; app.att_channel = channel;
               else
                   ui_log(app,"Connection to attenuator disabled or not available.");
               end
            catch ME
                app.btnLaserOn.Value = false;
                ui_log(app,"Error: "+ME.message);
                ui_log(app,"Error in " + ME.stack(1).name +", in line "+ ME.stack(1).line);
            end
            
        end

        % Value changed function: txtWavelength
        function ui_txtWavelengthChanged(app, event)
            laserStat = double(app.btnLaserOn.Value);
            if laserStat 
                try  
                    c_ls = ui_selectLMS(app);
                    if c_ls.status
                        g = c_ls.instr;

                        dblWav = setLaserWav(app,g, app.txtWavelength.Value); 
                        app.txtWavelength.Value = dblWav;   
                        
                    end
                catch ME
                    app.btnLaserOn.Value = false;
                    ui_log(app,"Error: "+ME.message);
                    ui_log(app,"Error in " + ME.stack(1).name +", in line "+ ME.stack(1).line);
                end
            end
        end

        % Value changed function: txtPower
        function ui_txtPowerChanged(app, event)
            laserStat = double(app.btnLaserOn.Value);
            if laserStat
                try  
                    c_ls = ui_selectLMS(app);
                    if c_ls.status
                        g = c_ls.instr;
                        dblWav = setLaserPower(app,g, app.txtPower.Value); 
                        app.txtPower.Value = dblWav;   
                    end
                catch ME
                    app.btnLaserOn.Value = false;
                    ui_log(app,"Error: "+ME.message);
                    ui_log(app,"Error in " + ME.stack(1).name +", in line "+ ME.stack(1).line);
                end
            end
            
        end

        % Value changed function: cbPMAutoRange
        function ui_cbPMAutoRangeChanged(app, event)
            value = app.cbPMAutoRange.Value;
            app.dwPMRange.Enable = ~value;
            setPowerScan(app, app.btnPMRead.Value);  
        end

        % Value changed function: btnPMRead
        function ui_btnPMReadChanged(app, event)
            %This function will open the COM with the instrument and leave
            %it open for increase performance, and start the timed function
            %to query for the measurements in the Power Sensor.
            value = app.btnPMRead.Value;
            app.dwSensor.Enable = ~value;
            setPowerScan(app, value);  
        end

        % Menu selected function: menu_ConnectionSetup
        function menu_connSetup(app, event)
           
            configData = app.COM_config;
            connSetup = ConnectionSetup(configData);
            
            while (~connSetup.done)
                pause(0.1);
                if (~isvalid(connSetup)); break; end
            end
            if (isvalid(connSetup))
                configData = connSetup.dataOut;
                connSetup.close;
            end
            app.COM_config = configData;
            setWorkspace(app, configData);
        end

        % Menu selected function: menu_projectSave
        function menu_projectSaveSelected(app, event)
            file = app.project.filename;    
            if isempty(file)
               menu_projectSaveAsSelected(app, event);
            else
                try delete(file), catch, end 
                [ ~ , name , ext ] = fileparts( file );
                if ext == ".mat"
                    temp = app.project; 
                    save(file,'-struct','temp');
                else
                    project2h5(app,filename);
                end
            end
        end

        % Menu selected function: menu_projectSaveAs
        function menu_projectSaveAsSelected(app, event)
            %Save Project As
            [file,path] = uiputfile({'*.mat', 'Matlab File'; ...
                                     '*.smp', 'H5 structure'   }, ...
                                     'Save project as',strcat(app.saveFolder,'project'));
            filename =[path,file];
            name = '';
            
            if path~=0 
                [ ~ , name , ext ] = fileparts( filename );
                try delete(filename), catch, end 
                app.project.filename = filename;
                
                if ext == ".mat"
                    temp = app.project; 
                    save(filename,'-struct','temp');
                else
                    project2h5(app,filename);
                end
                
                app.saveFolder = path;
            end
            
            app.UIFigure.Name = sprintf("Spectral M - %s", name);
        end

        % Menu selected function: menu_projectNew
        function menu_projectNewSelected(app, event)
            app.project.measurements = {};
            app.project.filename = '';
            ID = zeros(0,1); Var1 = ID; Var2 = ID; Var3 = ID; Date = strings(0,1); %Initialize data for the table
            t = table(ID,Date,Var1,Var2,Var3);
            app.tblMeasurements.Data = t;
            cla(app.UIAxes01)
            figure(app.UIFigure)
            
            app.UIFigure.Name = sprintf("Spectral M");
        end

        % Menu selected function: menu_projectOpen
        function menu_projectOpenSelected(app, event)
            %This functions opens a  spectral measurements project and
            %saves it to the project data structure 
                path = app.saveFolder;
                [file,path] = uigetfile({'*.mat; *.smp; *.h5', 'All accepted formats';'*.mat', 'Matlab File';'*.smp', 'H5 structure' }, ...
                                        'Select the project to open', ...
                                        path, 'MultiSelect', 'off');
                filename = [path,file];
                if double(file)== 0, return, end

                if ~isempty(file)
                    [ ~ , ~ , ext ] = fileparts( file );
                    if path ~= 0
                        menu_projectNewSelected(app, event);
                        app.project.filename = [path, file];
                        app.saveFolder = path;
                        
                        struct = [];
                        if ext == ".mat"
                            struct = load(filename);
                        else
                            loc = '/project';
                            struct = h52struct(app.project.filename, loc);
                        end
                        
                        app.project.measurements = struct.measurements;
                      
                        % Populate the data for the project table
                        t = getTableData(app.project.measurements);
                        app.tblMeasurements.Data = t; 
                        ui_log(app,['Project opened from: ', file]);
                    end  
                end
                app.UIFigure.Name = sprintf("Spectral M - %s",file);
        end

        % Menu selected function: menu_ReconnectMenu
        function menu_ReconnectSelected(app, event)
            ui_log(app,'Refreshing all instrument connections.');
            configData = app.COM_config;
            setWorkspace(app, configData);
            ui_log(app,'Connections Updated.');
        end

        % Menu selected function: menu_SweepSave
        function menu_SweepExport(app, event)
            file = datestr(now,'yyyymmdd')+"_Spectra";
            path = app.saveFolder;
            
            [file,path] = uiputfile('*.mat','Export sweep data as',strcat(path,file));
            if path(1)~=0 && ~isempty(app.selectedRow)  
                if length(app.selectedRow)>1
                    for i = 1:length(app.selectedRow)
                        ID = app.tblMeasurements.Data(app.selectedRow(i),:).ID;
                        index = getIndex(app.project.measurements,ID);
                        data = app.project.measurements{index}.spectra;
                        
                        file2 = [file(1:end-4),'_',num2str(i),'.mat'];
                        save([path,file2],'data');
                    end
                else
                    data = app.measurement.spectra;
                    save([path,file],'data');
                end
                app.saveFolder = path;
            end
            figure(app.UIFigure);
        end

        % Menu selected function: menu_SweepOpen
        function menu_SweepImport(app, event)
            %This functions opens raw data of a spectral measurement and
            %loads it to the project, initializing the different measurement parameters. 
            path = app.saveFolder;
            [file,path] = uigetfile(strcat(path,"*.mat"),'MultiSelect', 'on');
            try
                if ~isempty(path)
                    if path ~= 0
                        if ischar(file), files{1} = file;
                        else, files = file; end
                        app.saveFolder = path;
                        sn = length(app.project.measurements);
                        ui_log(app,'A random ID is assigned to the loaded data.');
                        for i = 1:length(files)
                            data = load(strcat(path,files{i}));
                            spectra = data.data;
                            
                            new_measurement = newMeasurement(spectra);
                            %DEBUG: Add a routine to check that an ID has no replicas.
                            app.project.measurements{sn+i} = new_measurement;
                        end
                        
                        %Populate the data for the project table and save
                        %the active work datasets
                        t = getTableData(app.project.measurements);
                        app.tblMeasurements.Data = t;
                        
                        app.measurement = new_measurement;
                        s = plot(app.UIAxes01,app.measurement.spectra(:,1),app.measurement.spectra(:,2));
                        
                        s.DataTipTemplate.DataTipRows(1).Label = '\lambda =';
                        s.DataTipTemplate.DataTipRows(2).Label = 'Power =';
                        
                        s.DataTipTemplate.DataTipRows(1).Format = "%04.4f%*9";
                        s.DataTipTemplate.DataTipRows(2).Format = "%04.2f%*9";
                    end  
                end
                figure(app.UIFigure);
             catch EM
                 ui_log(app,EM.message)
             end
        end

        % Menu selected function: EffectiveIndexCalculationMenu
        function tools_EffectiveIndexCalculation(app, event)
             ModeCalculator;
        end

        % Value changed function: spAttenuation
        function spAttenuationValueChanged(app, event)
            value = app.EnableAttenuator.Value;
            try  
               if value
                   g = app.att_conn; 
                   channel = app.att_channel;
                   att = app.spAttenuation.Value;              
                   send(g,'INP%1d:ATT %1ddB',channel,att); 
               end
            catch ME
                app.btnLaserOn.Value = false;
                ui_log(app,"Error: "+ME.message);
                ui_log(app,"Error in " + ME.stack(1).name +", in line "+ ME.stack(1).line);
            end
        end

        % Value changed function: OffsetSpinner
        function OffsetSpinnerValueChanged(app, event)
            value = app.EnableAttenuator.Value;
            try  
               if value
                   g = app.att_conn; 
                   channel = app.att_channel;
                   off = app.OffsetSpinner.Value;              
                   send(g,'INP%1d:OFFS %1ddB',channel,off); 
               end
            catch ME
                app.btnLaserOn.Value = false;
                ui_log(app,"Error: "+ME.message);
                ui_log(app,"Error in " + ME.stack(1).name +", in line "+ ME.stack(1).line);
            end
            
        end

        % Button pushed function: btnDeleteMeasurement
        function btnDeleteMeasurementButtonPushed(app, event)
            %Works only when only one measurement is selected so far
            
            selection = sort([app.selectedRow]);
            if selection(end) <= length(app.project.measurements) && selection(1) >0
                carry = 0;
                for ind = 1:length(selection)
                    ID = app.tblMeasurements.Data(selection(ind)-carry,:).ID;
                    index = getIndex(app.project.measurements,ID);                
                    app.project.measurements(index) = []; %Delete the selected row 
                   
                    %Update the data for the project table
                    t = getTableData(app.project.measurements);
                    app.tblMeasurements.Data = t;
                    carry = carry+1;
                end
            end
        end

        % Button pushed function: btnPlot
        function btnPlotButtonPushed(app, event)
            %To be tested
            zopt = app.optZaxis.Value;
            xopt = app.optXaxis.Value;
            yopt = app.optYaxis.Value;
            
            x = get1DData(app.project.measurements,xopt);
            y = get1DData(app.project.measurements,yopt);
            
            figure(1); ax = gca; cla(ax);
            if strcmp(zopt, "Not used")
                hold (ax,'on');
                for i = 1:size(y,2)
                    try 
                        plot(ax,x(:,i),y(:,i));
                    catch ME
                        ui_log(app,ME.message);
                    end
                    set(ax,'xlimMode', 'auto');
                    grid on;
                end
                hold (ax,'off');
            else
                %Color Plot 
                if strcmp(xopt,"wavelength")||strcmp(yopt,"wavelength")
                    z = get1DData(app.project.measurements,zopt);
                    
                    
                    try 
                        if length(x) == size(z,1)
                            x = x(:,1);
                            pcolor(ax,x,y,z');
                        else
                            pcolor(ax,x,y,z);
                        end
                    catch EM
                        ui_log(app,EM.message);
                    end
                    shading('interp');
                    colormap('hot');
                    
                    
                    h = colorbar(ax);
                    set(get(h,'title'),'string',zopt);
                else
                    ui_log(app,"Either x or y need to be the wavelength to generate colorplots.");
                end
            end
            
            xlabel(ax,xopt);
            ylabel(ax,yopt);
        end

        % Button pushed function: ui_btnFindPeaks
        function ui_btnFindPeaksButtonPushed(app, event)
            for i = 1:length(app.selectedRow)
                ID = app.tblMeasurements.Data(app.selectedRow(i),:).ID;
                index = getIndex(app.project.measurements,ID);
                app.measurement = app.project.measurements{index};
                
                spectra = app.measurement.spectra;
                [pks,wav,filter] = SM_FindPeaks(app,spectra);
                if ~isempty(pks) 
                    ui_log(app,sprintf('Found %1d peaks.',length(pks)));
                    L = app.txtResL.Value;
                    ne = app.txtneff.Value;
                    type = app.optTOD.Value;
                    windowSc = 0.80;
                    
                    analysisParam = {};
                        analysisParam.filter = filter;
                        analysisParam.type = type;
                        analysisParam.L = L;
                        analysisParam.ne = ne;
                        analysisParam.windowSc = windowSc;
                    app.measurement.analysisParam = analysisParam;
                    
                   analysis = resonanceAnalysis(spectra,pks, wav,[L, ne, windowSc]);
                   app.measurement.peakAnalysis = analysis;    

                    %Update project info
                    app.measurement.peakAnalysis.pks = pks;
                    app.measurement.peakAnalysis.wav = wav;
                    
                    app.project.measurements{index} = app.measurement;
                else
                    ui_log(app,'No peaks were found, check filter parameters.')
                end
                
            end
            
            ui_updateData(app);
        end

        % Value changed function: panel01_InstrSelect
        function panel01_InstrSelectValueChanged(app, event)
            
            c_ls = ui_selectLMS(app);
            if c_ls.status
                g = c_ls.instr;
                lock(g,0);  %Unlock the laser
                
                %Update the optical switch status
                slot = swChannel(c_ls.instr);   
                state = send(g,'rout%1d:chan1?',slot);
                strings = strsplit(state,",");
                sw_state = (strings(2));
                app.swAKnob.Value = sw_state;
                
                %Update thr Max Min values of the sweep wavelength and
                %laser controls
                
                min = str2double(send(g,'sour0:wav? min'))*1e9;
                max = str2double(send(g,'sour0:wav? max'))*1e9;
                current = str2double(send(g,'sour0:wav?'))*1e9;
                currentP = str2double(send(g,'sour0:pow?'));
                laser_stat =  str2double(send(g,'sour0:pow:stat?'));
                cen = round((min+max)/2/10)*10;
                
                app.txtWavelength.Limits = [min, max];
                app.txtWavelength.Value = current;
                app.txtPower.Value = currentP;
                
                app.btnLaserOn.Value = laser_stat;
                app.panel01_LaserOutputLamp.Enable = laser_stat;
                
                app.spinStart.Limits = [min, max];
                app.spinStart.Value = cen-20;
                app.spinStop.Limits = [min, max];
                app.spinStop.Value = cen+20;
            end
        end

        % Value changed function: swAKnob
        function swAKnobValueChanged(app, event)
            value = app.swAKnob.Value;
            c_ls = ui_selectLMS(app);
            if c_ls.status
                g = c_ls.instr;
                slot = swChannel(c_ls.instr);                 
                send(g,'rout%1d:chan1 A,%s',slot,value);
            end
        end

        % Value changed function: ui_displayPeaksCheckBox
        function ui_displayPeaksCheckBoxValueChanged(app, event)
            value = app.ui_displayPeaksCheckBox.Value;
            ui_updateData(app);
        end

        % Button pushed function: ui_btnRemoveBack
        function ui_btnRemoveBackButtonPushed(app, event)
            value = app.ui_btnRemoveBack.Value;
            
            for i = 1:length(app.selectedRow)
                ID = app.tblMeasurements.Data(app.selectedRow(i),:).ID;
                index = getIndex(app.project.measurements,ID);
                app.measurement = app.project.measurements{index};
                spectra = app.measurement.spectra;
                
                %Get the data
                s = spectra(:,2);
                wav = spectra(:,1);
                
                %Apply a high pass filter to remove backround signal
                wp = app.txtHPB.Value;
                s2 = highpass(s,wp);
              
                %Save a new measurement file with the modified data
                new_measurement = newMeasurement();
                new_measurement.analysisParam = app.measurement.analysisParam;
                new_measurement.spectra = [wav, s2];
                new_measurement.desc = "Background removed";
                
                %Update the global variables
                app.measurement = new_measurement;
                dataSize = length(app.project.measurements);
                app.project.measurements{dataSize+1} = new_measurement;
                app.selectedRow = dataSize+1;
                
                t = getTableData(app.project.measurements);
                app.tblMeasurements.Data = t;
                
            end
            
            ui_updateData(app);
        end

        % Menu selected function: ResetControlsMenu
        function ResetControlsMenuSelected(app, event)
            updateControls(app);
        end

        % Callback function: not associated with a component
        function spPMChannelValueChanged(app, event)
            value = app.btnPMRead.Value;
            setPowerScan(app, 0);
            setPowerScan(app, value);
        end

        % Value changed function: dwPMRange
        function dwPMRangeValueChanged(app, event)
            value = app.btnPMRead.Value;
            setPowerScan(app, 0);
            setPowerScan(app, value);
        end

        % Value changed function: SMU_type
        function SMU_typeValueChanged(app, event)
            value = app.SMU_type.Value;
            if strcmp(value,'Voltage')
                app.SMU_units_sta.Items={'V','mV','uV'}
                app.SMU_units_sto.Text = app.SMU_units_sta.Value;
            else
                app.SMU_units_sta.Items={'A','mA','uA'}
                app.SMU_units_sto.Text = app.SMU_units_sta.Value;
            end

        end

        % Callback function: not associated with a component
        function spPMSlotValueChanging(app, event)
            changingValue = event.Value;
            
            app.pm_slot = app.spPMSlot.Value;
            value = app.btnPMRead.Value;
            try
                setPowerScan(app, value);  
            catch ME
                app.btnPMRead.Value = false;
                ui_log(app,"Error: "+ME.message);
                ui_log(app,"Error in " + ME.stack(1).name +", in line "+ ME.stack(1).line);
                setPowerScan(app, 0);  
            end
            
        end

        % Value changed function: spPMSlot
        function spPMSlotValueChanged(app, event)
            app.pm_slot = app.spPMSlot.Value;
            value = app.btnPMRead.Value;
            try
                setPowerScan(app, value);  
            catch ME
                app.btnPMRead.Value = false;
                ui_log(app,"Error: "+ME.message);
                ui_log(app,"Error in " + ME.stack(1).name +", in line "+ ME.stack(1).line);
                setPowerScan(app, 0);  
            end
        end

        % Value changed function: SMU_units_sta
        function SMU_units_staValueChanged(app, event)
            value = app.SMU_units_sta.Value;
            app.SMU_units_sto.Text = value;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {946, 946};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {503, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Spectral M';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.menu_ReconnectMenu = uimenu(app.FileMenu);
            app.menu_ReconnectMenu.MenuSelectedFcn = createCallbackFcn(app, @menu_ReconnectSelected, true);
            app.menu_ReconnectMenu.Text = 'Reconnect';
            app.menu_ConnectionSetup = uimenu(app.FileMenu);
            app.menu_ConnectionSetup.MenuSelectedFcn = createCallbackFcn(app, @menu_connSetup, true);
            app.menu_ConnectionSetup.Text = 'Connection Setup';
            app.MeasurementMenu = uimenu(app.FileMenu);
            app.MeasurementMenu.Text = 'Measurement';
            app.menu_SweepSave = uimenu(app.MeasurementMenu);
            app.menu_SweepSave.MenuSelectedFcn = createCallbackFcn(app, @menu_SweepExport, true);
            app.menu_SweepSave.Text = 'Export';
            app.menu_SweepOpen = uimenu(app.MeasurementMenu);
            app.menu_SweepOpen.MenuSelectedFcn = createCallbackFcn(app, @menu_SweepImport, true);
            app.menu_SweepOpen.Text = 'Import';
            app.ProjectMenu = uimenu(app.FileMenu);
            app.ProjectMenu.Text = 'Project';
            app.menu_projectSave = uimenu(app.ProjectMenu);
            app.menu_projectSave.MenuSelectedFcn = createCallbackFcn(app, @menu_projectSaveSelected, true);
            app.menu_projectSave.Text = 'Save';
            app.menu_projectSaveAs = uimenu(app.ProjectMenu);
            app.menu_projectSaveAs.MenuSelectedFcn = createCallbackFcn(app, @menu_projectSaveAsSelected, true);
            app.menu_projectSaveAs.Text = 'Save As';
            app.menu_projectNew = uimenu(app.ProjectMenu);
            app.menu_projectNew.MenuSelectedFcn = createCallbackFcn(app, @menu_projectNewSelected, true);
            app.menu_projectNew.Text = 'New';
            app.menu_projectOpen = uimenu(app.ProjectMenu);
            app.menu_projectOpen.MenuSelectedFcn = createCallbackFcn(app, @menu_projectOpenSelected, true);
            app.menu_projectOpen.Text = 'Open';
            app.ToolsMenu = uimenu(app.UIFigure);
            app.ToolsMenu.Text = 'Tools';
            app.EffectiveIndexCalculationMenu = uimenu(app.ToolsMenu);
            app.EffectiveIndexCalculationMenu.MenuSelectedFcn = createCallbackFcn(app, @tools_EffectiveIndexCalculation, true);
            app.EffectiveIndexCalculationMenu.Text = 'Effective Index Calculation';
            app.ResetControlsMenu = uimenu(app.ToolsMenu);
            app.ResetControlsMenu.MenuSelectedFcn = createCallbackFcn(app, @ResetControlsMenuSelected, true);
            app.ResetControlsMenu.Text = 'Reset Controls';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.TabGroup2 = uitabgroup(app.GridLayout2);
            app.ProjectTab = uitab(app.TabGroup2);
            app.ProjectTab.Title = 'Project';
            app.GridLayout15 = uigridlayout(app.ProjectTab);
            app.MeasurementsPanel = uipanel(app.GridLayout15);
            app.MeasurementsPanel.Title = 'Measurements';
            app.GridLayout19 = uigridlayout(app.MeasurementsPanel);
            app.tblMeasurements = uitable(app.GridLayout19);
            app.tblMeasurements.ColumnName = {'ID'; 'Time'; 'Description'; 'Var 1'; 'Var 2'; 'Var 3'};
            app.tblMeasurements.RowName = {};
            app.tblMeasurements.ColumnSortable = [true true false false false];
            app.tblMeasurements.ColumnEditable = [false false true true true true];
            app.tblMeasurements.CellEditCallback = createCallbackFcn(app, @ui_tblMeasurementsCellEdit, true);
            app.tblMeasurements.CellSelectionCallback = createCallbackFcn(app, @ui_tblMeasurementsCellSelection, true);
            app.btnDeleteMeasurement = uibutton(app.GridLayout19, 'push');
            app.btnDeleteMeasurement.ButtonPushedFcn = createCallbackFcn(app, @btnDeleteMeasurementButtonPushed, true);
            app.btnDeleteMeasurement.Text = '-';
            app.ProjectManagementLabel = uilabel(app.GridLayout15);
            app.ProjectManagementLabel.Text = 'Project Management';
            app.PlottingPanel = uipanel(app.GridLayout15);
            app.PlottingPanel.Title = 'Plotting';
            app.GridLayout20 = uigridlayout(app.PlottingPanel);
            app.btnPlot = uibutton(app.GridLayout20, 'push');
            app.btnPlot.ButtonPushedFcn = createCallbackFcn(app, @btnPlotButtonPushed, true);
            app.btnPlot.Text = 'Plot';
            app.xaxisDropDownLabel = uilabel(app.GridLayout20);
            app.xaxisDropDownLabel.Text = 'x axis';
            app.optXaxis = uidropdown(app.GridLayout20);
            app.optXaxis.Items = {'wavelength', 'time', 'var 1', 'var 2', 'var 3'};
            app.optXaxis.Value = 'wavelength';
            app.yaxisDropDownLabel = uilabel(app.GridLayout20);
            app.yaxisDropDownLabel.Text = 'y axis';
            app.optYaxis = uidropdown(app.GridLayout20);
            app.optYaxis.Items = {'wavelength', 'intensity', 'Resonance Wav.', 'FSR', 'FWHM', 'Q. Factor', 'Group Index'};
            app.optYaxis.Value = 'intensity';
            app.zcoloraxisDropDownLabel = uilabel(app.GridLayout20);
            app.zcoloraxisDropDownLabel.Text = 'z (color) axis';
            app.optZaxis = uidropdown(app.GridLayout20);
            app.optZaxis.Items = {'Not used', 'intensity'};
            app.optZaxis.Value = 'Not used';
            app.ControlTab = uitab(app.TabGroup2);
            app.ControlTab.Title = 'Control';
            app.GridLayout13 = uigridlayout(app.ControlTab);
            app.LightwaveMeasurementSystemLabel = uilabel(app.GridLayout13);
            app.LightwaveMeasurementSystemLabel.Text = 'Lightwave Measurement System';
            app.ConnectionPanel = uipanel(app.GridLayout13);
            app.ConnectionPanel.Title = 'Connection';
            app.GridLayout8 = uigridlayout(app.ConnectionPanel);
            app.panel01_InstrumentDropDownLabel = uilabel(app.GridLayout8);
            app.panel01_InstrumentDropDownLabel.Text = 'Instrument';
            app.panel01_InstrSelect = uidropdown(app.GridLayout8);
            app.panel01_InstrSelect.Items = {};
            app.panel01_InstrSelect.ItemsData = {'1001'};
            app.panel01_InstrSelect.ValueChangedFcn = createCallbackFcn(app, @panel01_InstrSelectValueChanged, true);
            app.panel01_InstrSelect.Tag = 'intrSelectLbl_1';
            app.panel01_InstrSelect.Value = {};
            app.panel01_instrConnStat = uilamp(app.GridLayout8);
            app.panel01_instrConnStat.Enable = 'off';
            app.panel01_LaserOutputLampLabel = uilabel(app.GridLayout8);
            app.panel01_LaserOutputLampLabel.Text = 'Laser Output';
            app.panel01_LaserOutputLamp = uilamp(app.GridLayout8);
            app.panel01_LaserOutputLamp.Enable = 'off';
            app.LaserControlPanel = uipanel(app.GridLayout13);
            app.LaserControlPanel.Title = 'Laser Control';
            app.GridLayout9 = uigridlayout(app.LaserControlPanel);
            app.panel01_WavelengthSpinnerLabel = uilabel(app.GridLayout9);
            app.panel01_WavelengthSpinnerLabel.Text = 'Wavelength';
            app.txtWavelength = uispinner(app.GridLayout9);
            app.txtWavelength.Step = 0.01;
            app.txtWavelength.Limits = [1500 1640];
            app.txtWavelength.ValueDisplayFormat = '%.4f';
            app.txtWavelength.ValueChangedFcn = createCallbackFcn(app, @ui_txtWavelengthChanged, true);
            app.txtWavelength.Value = 1550;
            app.panel01_OutputPowerSpinnerLabel = uilabel(app.GridLayout9);
            app.panel01_OutputPowerSpinnerLabel.Text = 'Output Power';
            app.txtPower = uispinner(app.GridLayout9);
            app.txtPower.Step = 0.1;
            app.txtPower.Limits = [0 13];
            app.txtPower.ValueDisplayFormat = '%5.2f';
            app.txtPower.ValueChangedFcn = createCallbackFcn(app, @ui_txtPowerChanged, true);
            app.txtPower.Value = 3;
            app.nmLabel = uilabel(app.GridLayout9);
            app.nmLabel.Text = 'nm';
            app.dBmLabel = uilabel(app.GridLayout9);
            app.dBmLabel.Text = 'dBm';
            app.btnLaserOn = uibutton(app.GridLayout9, 'state');
            app.btnLaserOn.ValueChangedFcn = createCallbackFcn(app, @ui_btnLaserOn, true);
            app.btnLaserOn.Enable = 'off';
            app.btnLaserOn.Text = 'On';
            app.PowerSensorPanel = uipanel(app.GridLayout13);
            app.PowerSensorPanel.Title = 'Power Sensor';
            app.GridLayout10 = uigridlayout(app.PowerSensorPanel);
            app.AvgTLabel = uilabel(app.GridLayout10);
            app.AvgTLabel.Text = 'Avg T';
            app.panel01_AveragingTSpinner = uispinner(app.GridLayout10);
            app.panel01_AveragingTSpinner.Step = 10;
            app.panel01_AveragingTSpinner.Limits = [10 1000];
            app.panel01_AveragingTSpinner.ValueDisplayFormat = '%5.2f';
            app.panel01_AveragingTSpinner.Value = 100;
            app.panel01_RangeDropDownLabel = uilabel(app.GridLayout10);
            app.panel01_RangeDropDownLabel.Enable = 'off';
            app.panel01_RangeDropDownLabel.Text = 'Range';
            app.dwPMRange = uidropdown(app.GridLayout10);
            app.dwPMRange.Items = {'40 dBm', '30 dBm', '20 dBm', '10 dBm', '0 dBm', '-10 dBm', '-20 dBm', '-30 dBm', '-40 dBm', '-50 dBm', '-60 dBm', '-70 dBm'};
            app.dwPMRange.ItemsData = {'40', '30', '20', '10', '0', '-10', '-20', '-30', '-40', '-50', '-60', '-70'};
            app.dwPMRange.ValueChangedFcn = createCallbackFcn(app, @dwPMRangeValueChanged, true);
            app.dwPMRange.Enable = 'off';
            app.dwPMRange.Value = '0';
            app.panel01_pwrMeas1 = uilabel(app.GridLayout10);
            app.panel01_pwrMeas1.Text = '0.00';
            app.panel01_msLabel = uilabel(app.GridLayout10);
            app.panel01_msLabel.Text = 'ms';
            app.btnPMRead = uibutton(app.GridLayout10, 'state');
            app.btnPMRead.ValueChangedFcn = createCallbackFcn(app, @ui_btnPMReadChanged, true);
            app.btnPMRead.Enable = 'off';
            app.btnPMRead.Text = 'Run';
            app.cbPMAutoRange = uicheckbox(app.GridLayout10);
            app.cbPMAutoRange.ValueChangedFcn = createCallbackFcn(app, @ui_cbPMAutoRangeChanged, true);
            app.cbPMAutoRange.Text = 'Auto';
            app.cbPMAutoRange.Value = true;
            app.panel01_dBmLabel_2 = uilabel(app.GridLayout10);
            app.panel01_dBmLabel_2.Text = 'dBm';
            app.InstrumentLabel_2 = uilabel(app.GridLayout10);
            app.InstrumentLabel_2.Text = 'Instrument';
            app.dwSensor = uidropdown(app.GridLayout10);
            app.dwSensor.Items = {};
            app.dwSensor.ValueChangedFcn = createCallbackFcn(app, @ui_dwSensorChanged, true);
            app.dwSensor.Value = {};
            app.Label_3 = uilabel(app.GridLayout10);
            app.Label_3.Enable = 'off';
            app.Label_3.Text = '';
            app.spPMChannel = uispinner(app.GridLayout10);
            app.spPMChannel.ValueChangedFcn = createCallbackFcn(app, @spPMChannelValueChanged, true);
            app.spPMChannel.Enable = 'off';
            app.spPMSlot = uispinner(app.GridLayout10);
            app.spPMSlot.ValueChangedFcn = createCallbackFcn(app, @spPMSlotValueChanged, true);
            app.spPMSlot.Enable = 'off';
            app.OpticalSwitchPanel = uipanel(app.GridLayout13);
            app.OpticalSwitchPanel.Title = 'Optical Switch';
            app.GridLayout11 = uigridlayout(app.OpticalSwitchPanel);
            app.swAKnob = uiknob(app.GridLayout11, 'discrete');
            app.swAKnob.Items = {'1', '2', '3', '4'};
            app.swAKnob.ValueChangedFcn = createCallbackFcn(app, @swAKnobValueChanged, true);
            app.swAKnob.Enable = 'off';
            app.swAKnob.Value = '1';
            app.AttenuatorPanel = uipanel(app.GridLayout13);
            app.AttenuatorPanel.Title = 'Attenuator';
            app.GridLayout23 = uigridlayout(app.AttenuatorPanel);
            app.InstrumentLabel = uilabel(app.GridLayout23);
            app.InstrumentLabel.Text = 'Instrument';
            app.dwAttenuator = uidropdown(app.GridLayout23);
            app.dwAttenuator.Items = {};
            app.dwAttenuator.Value = {};
            app.AttenuationSpinnerLabel = uilabel(app.GridLayout23);
            app.AttenuationSpinnerLabel.Text = 'Attenuation';
            app.spAttenuation = uispinner(app.GridLayout23);
            app.spAttenuation.ValueChangedFcn = createCallbackFcn(app, @spAttenuationValueChanged, true);
            app.OffsetSpinnerLabel = uilabel(app.GridLayout23);
            app.OffsetSpinnerLabel.Text = 'Offset';
            app.OffsetSpinner = uispinner(app.GridLayout23);
            app.OffsetSpinner.ValueChangedFcn = createCallbackFcn(app, @OffsetSpinnerValueChanged, true);
            app.EnableAttenuator = uibutton(app.GridLayout23, 'state');
            app.EnableAttenuator.ValueChangedFcn = createCallbackFcn(app, @ui_btnEnableAttenuator, true);
            app.EnableAttenuator.Tooltip = {'For the wavelength select, a '};
            app.EnableAttenuator.Text = 'Enable';
            app.dBmLabel_6 = uilabel(app.GridLayout23);
            app.dBmLabel_6.Text = 'dBm';
            app.dBmLabel_7 = uilabel(app.GridLayout23);
            app.dBmLabel_7.Text = 'dBm';
            app.spAttChannel = uispinner(app.GridLayout23);
            app.spAttChannel.Limits = [1 2];
            app.spAttChannel.Enable = 'off';
            app.spAttChannel.Value = 1;
            app.SMUPanel = uipanel(app.GridLayout13);
            app.SMUPanel.Title = 'SMU';
            app.GridLayout25 = uigridlayout(app.SMUPanel);
            app.InstrumentLabel_3 = uilabel(app.GridLayout25);
            app.InstrumentLabel_3.Text = 'Instrument';
            app.dwSMU = uidropdown(app.GridLayout25);
            app.dwSMU.Items = {};
            app.dwSMU.Value = {};
            app.cmdEditFieldLabel = uilabel(app.GridLayout25);
            app.cmdEditFieldLabel.Enable = 'off';
            app.cmdEditFieldLabel.Text = 'cmd';
            app.cmdEditField = uieditfield(app.GridLayout25, 'text');
            app.cmdEditField.Enable = 'off';
            app.sendButton = uibutton(app.GridLayout25, 'push');
            app.sendButton.Enable = 'off';
            app.sendButton.Text = 'send';
            app.MeasurementTab = uitab(app.TabGroup2);
            app.MeasurementTab.Title = 'Measurement';
            app.GridLayout17 = uigridlayout(app.MeasurementTab);
            app.MeasurementParametersLabel = uilabel(app.GridLayout17);
            app.MeasurementParametersLabel.Text = 'Measurement Parameters';
            app.LossMeasurementWavelengthScanPanel = uipanel(app.GridLayout17);
            app.LossMeasurementWavelengthScanPanel.Title = 'Loss Measurement (Wavelength Scan)';
            app.GridLayout18 = uigridlayout(app.LossMeasurementWavelengthScanPanel);
            app.ScanStartSpinnerLabel = uilabel(app.GridLayout18);
            app.ScanStartSpinnerLabel.Text = 'Scan Start';
            app.spinStart = uispinner(app.GridLayout18);
            app.spinStart.Limits = [1280 1640];
            app.spinStart.Value = 1530;
            app.ScanStopSpinnerLabel = uilabel(app.GridLayout18);
            app.ScanStopSpinnerLabel.Text = 'Scan Stop';
            app.spinStop = uispinner(app.GridLayout18);
            app.spinStop.Limits = [1500 1640];
            app.spinStop.Value = 1570;
            app.StepSizeSpinnerLabel = uilabel(app.GridLayout18);
            app.StepSizeSpinnerLabel.Text = 'Step Size';
            app.spinStep = uispinner(app.GridLayout18);
            app.spinStep.Step = 10;
            app.spinStep.Limits = [0.1 100];
            app.spinStep.ValueDisplayFormat = '%11.1f';
            app.spinStep.Value = 10;
            app.nmLabel_3 = uilabel(app.GridLayout18);
            app.nmLabel_3.Text = 'nm';
            app.nmLabel_4 = uilabel(app.GridLayout18);
            app.nmLabel_4.Text = 'nm';
            app.pmLabel = uilabel(app.GridLayout18);
            app.pmLabel.Text = 'pm';
            app.ScanSpeedLabel = uilabel(app.GridLayout18);
            app.ScanSpeedLabel.Text = 'Scan Speed';
            app.spinSpeed = uidropdown(app.GridLayout18);
            app.spinSpeed.Items = {'Auto', '0.5 nm/s', '5 nm/s', '10 nm/s', '20 nm/s', '40 nm/s', '80 nm/s'};
            app.spinSpeed.ItemsData = {'0.0', '0.5', '5', '10', '20', '40', '80'};
            app.spinSpeed.Value = '10';
            app.AttenuationSweepPanel = uipanel(app.GridLayout17);
            app.AttenuationSweepPanel.Title = 'Attenuation Sweep';
            app.GridLayout22 = uigridlayout(app.AttenuationSweepPanel);
            app.AttenuationEnabled = uicheckbox(app.GridLayout22);
            app.AttenuationEnabled.Enable = 'off';
            app.AttenuationEnabled.Text = 'Enabled';
            app.StartSpinnerLabel = uilabel(app.GridLayout22);
            app.StartSpinnerLabel.Text = 'Start';
            app.AttenuationStart = uispinner(app.GridLayout22);
            app.StopSpinnerLabel = uilabel(app.GridLayout22);
            app.StopSpinnerLabel.Text = 'Stop';
            app.AttenuationStoper = uispinner(app.GridLayout22);
            app.StepsSpinnerLabel = uilabel(app.GridLayout22);
            app.StepsSpinnerLabel.Text = 'Steps';
            app.AttenuationSteps = uispinner(app.GridLayout22);
            app.dBLabel = uilabel(app.GridLayout22);
            app.dBLabel.Text = 'dB';
            app.dBLabel_2 = uilabel(app.GridLayout22);
            app.dBLabel_2.Text = 'dB';
            app.SMUSweepPanel = uipanel(app.GridLayout17);
            app.SMUSweepPanel.Title = 'SMU Sweep';
            app.GridLayout24 = uigridlayout(app.SMUSweepPanel);
            app.ParameterSweepDropDownLabel = uilabel(app.GridLayout24);
            app.ParameterSweepDropDownLabel.Text = 'Parameter Sweep';
            app.SMU_type = uidropdown(app.GridLayout24);
            app.SMU_type.Items = {'Voltage', 'Current'};
            app.SMU_type.ValueChangedFcn = createCallbackFcn(app, @SMU_typeValueChanged, true);
            app.SMU_type.Value = 'Voltage';
            app.StartEditFieldLabel = uilabel(app.GridLayout24);
            app.StartEditFieldLabel.Text = 'Start';
            app.SMU_start = uieditfield(app.GridLayout24, 'numeric');
            app.StopEditFieldLabel = uilabel(app.GridLayout24);
            app.StopEditFieldLabel.Text = 'Stop';
            app.SMU_stop = uieditfield(app.GridLayout24, 'numeric');
            app.SMU_stop.Value = 2;
            app.NumberofstepsEditFieldLabel = uilabel(app.GridLayout24);
            app.NumberofstepsEditFieldLabel.Text = 'Number of steps';
            app.SMU_nos = uieditfield(app.GridLayout24, 'numeric');
            app.SMU_nos.Value = 21;
            app.SMU_units_sta = uidropdown(app.GridLayout24);
            app.SMU_units_sta.Items = {'V', 'mV', 'uV'};
            app.SMU_units_sta.ValueChangedFcn = createCallbackFcn(app, @SMU_units_staValueChanged, true);
            app.SMU_units_sta.Value = 'V';
            app.SMU_Enabled = uicheckbox(app.GridLayout24);
            app.SMU_Enabled.Enable = 'off';
            app.SMU_Enabled.Text = 'Enabled';
            app.SMU_units_sto = uilabel(app.GridLayout24);
            app.SMU_units_sto.Text = 'V';
            app.DataAnalysisTab = uitab(app.TabGroup2);
            app.DataAnalysisTab.Title = 'Data Analysis';
            app.GridLayout16 = uigridlayout(app.DataAnalysisTab);
            app.Peakfinder1RedPanel = uipanel(app.GridLayout16);
            app.Peakfinder1RedPanel.Title = 'Peak finder 1 (Red)';
            app.GridLayout7 = uigridlayout(app.Peakfinder1RedPanel);
            app.HeightSpinnerLabel = uilabel(app.GridLayout7);
            app.HeightSpinnerLabel.Text = 'Height';
            app.HeightSpinner = uispinner(app.GridLayout7);
            app.HeightSpinner.Value = -20;
            app.ProminenceSpinnerLabel = uilabel(app.GridLayout7);
            app.ProminenceSpinnerLabel.Text = 'Prominence';
            app.ProminenceSpinner = uispinner(app.GridLayout7);
            app.ProminenceSpinner.Value = -15;
            app.MaxWidthSpinnerLabel = uilabel(app.GridLayout7);
            app.MaxWidthSpinnerLabel.Text = 'Max Width';
            app.MaxWidthSpinner = uispinner(app.GridLayout7);
            app.MaxWidthSpinner.Value = 0.5;
            app.MinWidthSpinnerLabel = uilabel(app.GridLayout7);
            app.MinWidthSpinnerLabel.Text = 'Min Width';
            app.MinWidthSpinner = uispinner(app.GridLayout7);
            app.PeakSpacingLabel = uilabel(app.GridLayout7);
            app.PeakSpacingLabel.Text = 'Peak Spacing';
            app.DistanceSpinner = uispinner(app.GridLayout7);
            app.DistanceSpinner.Value = 3;
            app.dBmLabel_5 = uilabel(app.GridLayout7);
            app.dBmLabel_5.Text = 'dBm';
            app.nmLabel_6 = uilabel(app.GridLayout7);
            app.nmLabel_6.Text = 'nm';
            app.nmLabel_7 = uilabel(app.GridLayout7);
            app.nmLabel_7.Text = 'nm';
            app.nmLabel_8 = uilabel(app.GridLayout7);
            app.nmLabel_8.Text = 'nm';
            app.MinPeakHeightCheckBox = uicheckbox(app.GridLayout7);
            app.MinPeakHeightCheckBox.Text = '';
            app.MinPeakHeightCheckBox.Value = true;
            app.MinPeakProminenceCheckBox = uicheckbox(app.GridLayout7);
            app.MinPeakProminenceCheckBox.Text = '';
            app.MinPeakProminenceCheckBox.Value = true;
            app.MaxPeakWidthCheckBox = uicheckbox(app.GridLayout7);
            app.MaxPeakWidthCheckBox.Text = '';
            app.MinPeakWidthCheckBox = uicheckbox(app.GridLayout7);
            app.MinPeakWidthCheckBox.Text = '';
            app.MinPeakDistanceCheckBox = uicheckbox(app.GridLayout7);
            app.MinPeakDistanceCheckBox.Text = '';
            app.InterferenceAnalysisPanel = uipanel(app.GridLayout16);
            app.InterferenceAnalysisPanel.Title = 'Interference Analysis';
            app.GridLayout3 = uigridlayout(app.InterferenceAnalysisPanel);
            app.LnmLabel = uilabel(app.GridLayout3);
            app.LnmLabel.Text = 'L (nm)';
            app.txtResL = uieditfield(app.GridLayout3, 'numeric');
            app.txtResL.Value = 62831;
            app.TypeofdeviceDropDownLabel = uilabel(app.GridLayout3);
            app.TypeofdeviceDropDownLabel.Text = 'Type of device';
            app.optTOD = uidropdown(app.GridLayout3);
            app.optTOD.Items = {'Resonator', 'Interferometer', 'Other'};
            app.optTOD.Value = 'Resonator';
            app.neffectiveEditFieldLabel = uilabel(app.GridLayout3);
            app.neffectiveEditFieldLabel.Text = 'n effective ';
            app.txtneff = uieditfield(app.GridLayout3, 'numeric');
            app.txtneff.Limits = [1 50];
            app.txtneff.Value = 3.4;
            app.DataAnalysisParametersLabel = uilabel(app.GridLayout16);
            app.DataAnalysisParametersLabel.Text = 'Data Analysis Parameters';
            app.RemoveBackgroundPanel = uipanel(app.GridLayout16);
            app.RemoveBackgroundPanel.Title = 'Remove Background';
            app.GridLayout3_2 = uigridlayout(app.RemoveBackgroundPanel);
            app.HighPassBandFreqLabel = uilabel(app.GridLayout3_2);
            app.HighPassBandFreqLabel.Text = 'High Pass Band Freq.';
            app.txtHPB = uieditfield(app.GridLayout3_2, 'numeric');
            app.txtHPB.Limits = [0 1];
            app.txtHPB.ValueDisplayFormat = '%11.3g';
            app.txtHPB.Value = 0.001;
            app.ui_btnRemoveBack = uibutton(app.GridLayout3_2, 'push');
            app.ui_btnRemoveBack.ButtonPushedFcn = createCallbackFcn(app, @ui_btnRemoveBackButtonPushed, true);
            app.ui_btnRemoveBack.Text = 'Run';
            app.Image = uiimage(app.GridLayout2);
            app.Image.ImageSource = 'logo.png';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout21 = uigridlayout(app.RightPanel);
            app.UIAxes01 = uiaxes(app.GridLayout21);
            title(app.UIAxes01, 'Spectrum')
            xlabel(app.UIAxes01, 'wavelength (nm)')
            ylabel(app.UIAxes01, 'intensity (dBm)')
            app.tblResonanceAnalysis = uitable(app.GridLayout21);
            app.tblResonanceAnalysis.ColumnName = {'Peak'; 'Wavelength'; 'FSR'; 'FWHM'; 'Q. Factor'; 'Group Index'; 'Loss Coeficient'};
            app.tblResonanceAnalysis.RowName = {};
            app.ui_btnFindPeaks = uibutton(app.GridLayout21, 'push');
            app.ui_btnFindPeaks.ButtonPushedFcn = createCallbackFcn(app, @ui_btnFindPeaksButtonPushed, true);
            app.ui_btnFindPeaks.Text = 'Find Peaks';
            app.ui_btnRunSweep = uibutton(app.GridLayout21, 'state');
            app.ui_btnRunSweep.ValueChangedFcn = createCallbackFcn(app, @ui_btnRunSweepValueChanged, true);
            app.ui_btnRunSweep.Text = 'Run Measurement';
            app.ui_displayPeaksCheckBox = uicheckbox(app.GridLayout21);
            app.ui_displayPeaksCheckBox.ValueChangedFcn = createCallbackFcn(app, @ui_displayPeaksCheckBoxValueChanged, true);
            app.ui_displayPeaksCheckBox.Text = 'Display Peaks';
            app.logger = uitextarea(app.GridLayout21);
            app.logger.Value = {'Spectral Measurements 1.5.6  (C) NYUAD PRL 2022'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SpectralM_v1_5_6

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end